classdef DOE_analysis_tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NbofdepthlevelsLabel           matlab.ui.control.Label
        SignificantparametersTextArea  matlab.ui.control.TextArea
        SignificantparametersLabel     matlab.ui.control.Label
        RSquaredEditField              matlab.ui.control.NumericEditField
        AdjustedR2valueLabel           matlab.ui.control.Label
        ModelFormulaTextArea           matlab.ui.control.TextArea
        PlotRsquaredButton             matlab.ui.control.Button
        xAxisMaxValueEditField         matlab.ui.control.NumericEditField
        xaxismaxvalueEditFieldLabel    matlab.ui.control.Label
        ParametersselectionPanel       matlab.ui.container.Panel
        ELoadCheckBox                  matlab.ui.control.CheckBox
        DDiskspeedCheckBox             matlab.ui.control.CheckBox
        CAbrasiveparticlesizeCheckBox  matlab.ui.control.CheckBox
        BTimeCheckBox                  matlab.ui.control.CheckBox
        ModelDropDownLabel             matlab.ui.control.Label
        ModelInterceptCheckbox         matlab.ui.control.CheckBox
        ModelDropDown                  matlab.ui.control.DropDown
        HighDepthEditField             matlab.ui.control.NumericEditField
        HighdepthEditFieldLabel        matlab.ui.control.Label
        LowDepthEditField              matlab.ui.control.NumericEditField
        LowdepthEditFieldLabel         matlab.ui.control.Label
        DepthSlider                    matlab.ui.control.RangeSlider
        DepthLabel                     matlab.ui.control.Label
        DepthIncrementEditField        matlab.ui.control.NumericEditField
        NbDepthsLevelsEditField        matlab.ui.control.NumericEditField
        DepthincrementEditFieldLabel   matlab.ui.control.Label
        ResidualsFittedUIAxes          matlab.ui.control.UIAxes
        NormalProbabilityUIAxes        matlab.ui.control.UIAxes
        AdjustedRSquaredUIAxes         matlab.ui.control.UIAxes
        ParetoChartUIAxes              matlab.ui.control.UIAxes
    end

    methods (Access = private)
        function [model,terms, t_vals, t_crit] = plotMainEffectsDOE(app,nbDepthLevels,lowDepth,maxDepth)
            % This function generates a DoE based on the parameters and model chosen
            % by the user and plots the Pareto chart of main effects.
            [~,codedDOE,response] = generateMyDOE(nbDepthLevels,lowDepth,maxDepth);

            codedRunMatrix = array2table(codedDOE, 'VariableNames', {'A','B','C','D','E'});
            codedRunMatrix = [codedRunMatrix,response];
            codedRunMatrix = renamevars(codedRunMatrix,"Residual stress","ResidualStress");

            if app.BTimeCheckBox.Value == false
                codedRunMatrix.B = [];
            end
            if app.CAbrasiveparticlesizeCheckBox.Value == false
                codedRunMatrix.C = [];
            end
            if app.DDiskspeedCheckBox.Value == false
                codedRunMatrix.D = [];
            end
            if app.ELoadCheckBox.Value == false
                codedRunMatrix.E = [];
            end
        
            if strcmp(app.ModelDropDown.Value,'personalized')
                try
                    model = fitlm(codedRunMatrix,char(app.ModelFormulaTextArea.Value),Intercept=app.ModelInterceptCheckbox.Value);
                catch
                    uialert(app.UIFigure,sprintf("The model you entered does not work.\n %s",char(app.ModelFormulaTextArea.Value)),"Model not valid")
                    app.ModelDropDown.Value = "interactions";
                    enableFieldsModel(app)
                    return
                end
            else
                model = fitlm(codedRunMatrix,app.ModelDropDown.Value,Intercept=app.ModelInterceptCheckbox.Value);
            end

            mask = model.Coefficients.Estimate~=0;
            coef_tbl = model.Coefficients(mask,:);
            terms = coef_tbl.Properties.RowNames(2:end);
            % disp(terms)
            t_vals = abs(coef_tbl.tStat(2:end));
            
            [t_sorted, idx] = sort(t_vals, 'ascend');
            terms_sorted = terms(idx);
            barh(app.ParetoChartUIAxes,t_sorted, 'FaceColor',[0.2 0.55 0.8],'EdgeColor','none');
            yticks(app.ParetoChartUIAxes,1:numel(terms_sorted));
            yticklabels(app.ParetoChartUIAxes,terms_sorted);
            % Reference line at t-critical (two-tailed, α=0.05)
            t_crit  = tinv(0.95,model.DFE);
            xline(app.ParetoChartUIAxes,t_crit,'--r',sprintf('t_{crit}(%.2f)', t_crit),'LabelVerticalAlignment','bottom','LineWidth',1.2);

            app.ModelFormulaTextArea.Value = sprintf('%s ~ %s',model.Formula.ResponseName,model.Formula.LinearPredictor);
            app.RSquaredEditField.Value = model.Rsquared.Adjusted;
        end
        
        function enableFieldsModel(app)
            if strcmp(app.ModelDropDown.Value, 'personalized')
                % app.ModelInterceptCheckbox.Enable = "off";
                app.ModelFormulaTextArea.Editable = "on";
            else
                % app.ModelInterceptCheckbox.Enable = "on";
                app.ModelFormulaTextArea.Editable = "off";
            end
        end
        
        function plotResidualsFittedDOE(app,model)
            scatter(app.ResidualsFittedUIAxes,model.Fitted,model.Residuals.Raw,60,'filled','MarkerFaceColor',[.2,.45,.7],'MarkerFaceAlpha',.8)
            yline(app.ResidualsFittedUIAxes,0,'--','Color',[.5 .5 .5])
            xlim(app.ResidualsFittedUIAxes,[-inf inf])
            ylim(app.ResidualsFittedUIAxes,[-inf inf])
        end
        
        function plotNormalProbabilityDOE(app,model)
            cla(app.NormalProbabilityUIAxes)
            normplot(app.NormalProbabilityUIAxes,model.Residuals.Raw);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function InitializeCallback(app)
            model = plotMainEffectsDOE(app,app.nbDepthLevels,app.LowDepthEditField.Value,app.HighDepthEditField.Value);
            plotResidualsFittedDOE(app,model)
            plotNormalProbabilityDOE(app,model)
        end

        % Value changed function: DepthSlider
        function ChangeDepthCallback(app, event)
            if event.Value(1)~=event.PreviousValue(1)
                app.DepthSlider.Value(2) = event.Value(1)+app.DepthIncrementEditField.Value;
            elseif event.Value(2)~=event.PreviousValue(2)
                app.DepthSlider.Value(1) = event.Value(2)-app.DepthIncrementEditField.Value;
            end
            app.LowDepthEditField.Value = app.DepthSlider.Value(1);
            app.HighDepthEditField.Value = app.DepthSlider.Value(2);
            plotMainEffectsDOE(app,app.nbDepthLevels,app.LowDepthEditField.Value,app.HighDepthEditField.Value)
        end

        % Value changed function: BTimeCheckBox, 
        % ...and 7 other components
        function ModelChangedCallback(app, event)
            enableFieldsModel(app)
            model = plotMainEffectsDOE(app,app.nbDepthLevels,app.LowDepthEditField.Value,app.HighDepthEditField.Value);
            plotResidualsFittedDOE(app,model)
            plotNormalProbabilityDOE(app,model)
        end

        % Value changed function: xAxisMaxValueEditField
        function AxesChangedCallback(app, event)
            app.ParetoChartUIAxes.XLim(2) = app.xAxisMaxValueEditField.Value;
        end

        % Button pushed function: PlotRsquaredButton
        function PlotRsquaredButtonPushed(app, event)
            % This function plots all adjusted R squared for the model used
            minDepth = app.DepthSlider.Limits(1);
            maxDepth = app.DepthSlider.Limits(2);
            depthIncrement = app.DepthIncrementEditField.Value;
            nbIncrements = (maxDepth-minDepth)/depthIncrement;
            % barPlotEdges = minDepth:depthIncrement:maxDepth;

            lowDepth = minDepth;

            significantParameters = string(zeros(20,nbIncrements));
            adjustedRSquaredList = zeros(1,nbIncrements);
            barPlotDepths = zeros(1,nbIncrements);

            f = waitbar(0,'Computing');
            for depthIteration = 1:nbIncrements
                waitbar(nbIncrements/depthIteration,f,sprintf('Iteration %d/%d',depthIteration,nbIncrements))
                [model, terms, t_vals, t_crit] = plotMainEffectsDOE(app,app.nbDepthLevels,lowDepth,lowDepth+depthIncrement);
                for parameterIndex = 1:length(t_vals)
                    if t_vals(parameterIndex) > t_crit
                        significantParameters(parameterIndex,depthIteration) = terms{parameterIndex};
                    end
                end
                
                barPlotDepths(depthIteration) = lowDepth + depthIncrement/2;
                adjustedRSquaredList(depthIteration) = model.Rsquared.Adjusted;
            
                lowDepth = lowDepth + depthIncrement;
            end
            close(f)
            
            bar(app.AdjustedRSquaredUIAxes,barPlotDepths,adjustedRSquaredList)
            hold(app.AdjustedRSquaredUIAxes,"on")
            plot(app.AdjustedRSquaredUIAxes,barPlotDepths,adjustedRSquaredList,'-o')
            % xticklabels(app.AdjustedRSquaredUIAxes,barPlotEdges)
            hold(app.AdjustedRSquaredUIAxes,"off")

            app.SignificantparametersTextArea.Value = unique(significantParameters);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ParetoChartUIAxes = uiaxes(app.UIFigure);
            title(app.ParetoChartUIAxes, 'Pareto chart')
            xlabel(app.ParetoChartUIAxes, '|t-statistic|')
            zlabel(app.ParetoChartUIAxes, 'Z')
            app.ParetoChartUIAxes.XLim = [0 5];
            app.AdjustedRSquaredUIAxes = uiaxes(app.UIFigure);
            title(app.AdjustedRSquaredUIAxes, 'Adjusted R squared')
            xlabel(app.AdjustedRSquaredUIAxes, 'Depth (µm)')
            ylabel(app.AdjustedRSquaredUIAxes, 'Adjusted R Squared')
            zlabel(app.AdjustedRSquaredUIAxes, 'Z')
            app.AdjustedRSquaredUIAxes.XLim = [0.25 2];
            app.NormalProbabilityUIAxes = uiaxes(app.UIFigure);
            title(app.NormalProbabilityUIAxes, 'Normal probability plot of results')
            xlabel(app.NormalProbabilityUIAxes, 'Data')
            ylabel(app.NormalProbabilityUIAxes, 'Probability')
            zlabel(app.NormalProbabilityUIAxes, 'Z')
            app.NormalProbabilityUIAxes.XLim = [0 5];
            app.ResidualsFittedUIAxes = uiaxes(app.UIFigure);
            title(app.ResidualsFittedUIAxes, 'Scatter plot of residuals as a function of fitted values')
            xlabel(app.ResidualsFittedUIAxes, 'Fitted values')
            ylabel(app.ResidualsFittedUIAxes, 'Residuals')
            zlabel(app.ResidualsFittedUIAxes, 'Z')
            app.ResidualsFittedUIAxes.XLim = [0 5];
            app.DepthincrementEditFieldLabel = uilabel(app.UIFigure);
            app.DepthincrementEditFieldLabel.Text = 'Depth increment';
            app.NbDepthsLevelsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NbDepthsLevelsEditField.ValueChangedFcn = createCallbackFcn(app, @ModelChangedCallback, true);
            app.NbDepthsLevelsEditField.Value = 2;
            app.DepthIncrementEditField = uieditfield(app.UIFigure, 'numeric');
            app.DepthIncrementEditField.Value = 0.05;
            app.DepthLabel = uilabel(app.UIFigure);
            app.DepthLabel.Text = 'Depth';
            app.DepthSlider = uislider(app.UIFigure, 'range');
            app.DepthSlider.Limits = [0.25 2];
            app.DepthSlider.ValueChangedFcn = createCallbackFcn(app, @ChangeDepthCallback, true);
            app.DepthSlider.Step = 0.05;
            app.DepthSlider.Value = [0.25 0.3];
            app.LowdepthEditFieldLabel = uilabel(app.UIFigure);
            app.LowdepthEditFieldLabel.Text = 'Low depth';
            app.LowDepthEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowDepthEditField.Editable = 'off';
            app.LowDepthEditField.Value = 0.25;
            app.HighdepthEditFieldLabel = uilabel(app.UIFigure);
            app.HighdepthEditFieldLabel.Text = 'High depth';
            app.HighDepthEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighDepthEditField.Editable = 'off';
            app.HighDepthEditField.Value = 0.3;
            app.ModelDropDown = uidropdown(app.UIFigure);
            app.ModelDropDown.Items = {'constant', 'linear', 'interactions', 'purequadratic', 'quadratic', 'personalized'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelChangedCallback, true);
            app.ModelDropDown.Value = 'interactions';
            app.ModelInterceptCheckbox = uicheckbox(app.UIFigure);
            app.ModelInterceptCheckbox.ValueChangedFcn = createCallbackFcn(app, @ModelChangedCallback, true);
            app.ModelInterceptCheckbox.Text = 'Include constant term (intercept) in model?';
            app.ModelDropDownLabel = uilabel(app.UIFigure);
            app.ModelDropDownLabel.Text = 'Model';
            app.ParametersselectionPanel = uipanel(app.UIFigure);
            app.ParametersselectionPanel.Title = 'Parameters selection';
            app.BTimeCheckBox = uicheckbox(app.ParametersselectionPanel);
            app.BTimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ModelChangedCallback, true);
            app.BTimeCheckBox.Text = 'B = Time';
            app.BTimeCheckBox.Value = true;
            app.CAbrasiveparticlesizeCheckBox = uicheckbox(app.ParametersselectionPanel);
            app.CAbrasiveparticlesizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ModelChangedCallback, true);
            app.CAbrasiveparticlesizeCheckBox.Text = 'C = Abrasive particle size';
            app.CAbrasiveparticlesizeCheckBox.Value = true;
            app.DDiskspeedCheckBox = uicheckbox(app.ParametersselectionPanel);
            app.DDiskspeedCheckBox.ValueChangedFcn = createCallbackFcn(app, @ModelChangedCallback, true);
            app.DDiskspeedCheckBox.Text = 'D = Disk speed';
            app.DDiskspeedCheckBox.Value = true;
            app.ELoadCheckBox = uicheckbox(app.ParametersselectionPanel);
            app.ELoadCheckBox.ValueChangedFcn = createCallbackFcn(app, @ModelChangedCallback, true);
            app.ELoadCheckBox.Text = 'E = Load';
            app.xaxismaxvalueEditFieldLabel = uilabel(app.UIFigure);
            app.xaxismaxvalueEditFieldLabel.Text = 'x axis max value';
            app.xAxisMaxValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.xAxisMaxValueEditField.ValueChangedFcn = createCallbackFcn(app, @AxesChangedCallback, true);
            app.xAxisMaxValueEditField.Value = 5;
            app.PlotRsquaredButton = uibutton(app.UIFigure, 'push');
            app.PlotRsquaredButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRsquaredButtonPushed, true);
            app.PlotRsquaredButton.Text = 'Plot R squared';
            app.ModelFormulaTextArea = uitextarea(app.UIFigure);
            app.ModelFormulaTextArea.ValueChangedFcn = createCallbackFcn(app, @ModelChangedCallback, true);
            app.ModelFormulaTextArea.Editable = 'off';
            app.AdjustedR2valueLabel = uilabel(app.UIFigure);
            app.AdjustedR2valueLabel.Text = 'Model''s adjusted R squared value';
            app.RSquaredEditField = uieditfield(app.UIFigure, 'numeric');
            app.RSquaredEditField.Editable = 'off';
            app.RSquaredEditField.Value = 1;
            app.SignificantparametersLabel = uilabel(app.UIFigure);
            app.SignificantparametersLabel.Text = {'Significant'; 'parameters'};
            app.SignificantparametersTextArea = uitextarea(app.UIFigure);
            app.NbofdepthlevelsLabel = uilabel(app.UIFigure);
            app.NbofdepthlevelsLabel.Text = 'Nb of depth levels';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DOE_analysis_tool

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @InitializeCallback)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end